classdef parallelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        StatusLabel         matlab.ui.control.Label
        SimButton           matlab.ui.control.Button
        GridLayout3         matlab.ui.container.GridLayout
        UpdateLabel         matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        GenerateDataButton  matlab.ui.control.Button
        GridLayout2         matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        IsRunning = false % Simulation status flag
    end
    
    methods (Access = private)

        function updateView(app, f)
            % Update table data with results from future object
            app.UITable.Data = fetchOutputs(f);

            % Update graphics post-update
            set(app.UpdateLabel, Text="Table up to date.", FontColor=[0 0.4471 0.7412]);
            app.GenerateDataButton.Enable = true;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place app at the center of the screen
            g = groot; p = app.UIFigure.Position(3:4);
            app.UIFigure.Position = [(g.ScreenSize(3:4)-p)/2 p];

            % Initialize UITable with a 20x20 matrix of random numbers
            app.UITable.Data = rand(20);
            addStyle(app.UITable, uistyle("HorizontalAlignment", 'center'))
        end

        % Button pushed function: SimButton
        function SimButtonPushed(app, event)
            % Toggle simulation execution
            app.IsRunning = ~app.IsRunning;

            % Update simulation button
            if app.IsRunning
                set(app.SimButton, Text="Stop Simulation", BackgroundColor=[.87 .22 .19], Icon="end.svg");
            else
                set(app.SimButton, Text="Start Simulation", BackgroundColor=[0.4667 0.6745 0.1882], Icon="run.svg");
                app.StatusLabel.Text = "Status: Idle";
            end

            % Simulation
            while isvalid(app) && app.IsRunning 
                app.StatusLabel.Text = "Running: " + num2str(rand(1), '%.2f');
                pause(0.2)
            end
        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            % Update table data in background pool
            f = parfeval(backgroundPool, @generateData, 1);
            afterEach(f, @(~)app.updateView(f), 0);

            % Update graphics pre-update
            set(app.UpdateLabel, Text="Updating table data...", FontColor=[.5 .5 .5]);
            app.GenerateDataButton.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Parallel App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GenerateDataButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = 'Generate Random Data';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UpdateLabel = uilabel(app.GridLayout3);
            app.UpdateLabel.Text = 'Table up to date.';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SimButton = uibutton(app.GridLayout4, 'push');
            app.SimButton.ButtonPushedFcn = createCallbackFcn(app, @SimButtonPushed, true);
            app.SimButton.Icon = 'run.svg';
            app.SimButton.Text = 'Start Simulation';
            app.StatusLabel = uilabel(app.GridLayout4);
            app.StatusLabel.Text = 'Status: Idle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parallelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end